classdef mainapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ImportDataSetButton         matlab.ui.control.Button
        ChangeParametersButton      matlab.ui.control.Button
        PleaseuploaddatasetLabel    matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        PipelineTransportationCostModulesPanel  matlab.ui.container.Panel
        PipeMod                     matlab.ui.control.ListBox
        UserInputsLabel             matlab.ui.control.Label
        ElectricEnergyTransportationCostModulesPanel  matlab.ui.container.Panel
        ElecMod                     matlab.ui.control.ListBox
        ComputeCostsLabel           matlab.ui.control.Label
        ComputePipelineCostsButton  matlab.ui.control.Button
        ComputeElectricCostsButton  matlab.ui.control.Button
        ResultsLabel                matlab.ui.control.Label
        CostsPipeLamp               matlab.ui.control.Lamp
        CostsElecLamp               matlab.ui.control.Lamp
        CostsPipeLabel              matlab.ui.control.Label
        CostsElecLabel              matlab.ui.control.Label
    end


    properties (Access = public)
        Inputs % Description
        ProjectDuration = 20; % Description
        Length = 50; % Description
        Flow = 5; % Description
        Util = 70; % Description
        Discount = 7;
        MildSteel = 100; % Description
        StainlessSteel = 0; % Description
        TFlatRural
        TUrban
        THills
        TMountains
        TSandy
        TTrenched
        TDifficult
        TAverage
        COnRoad
        COnPipe
        COnOther
        COffPipe
        COffOther
    end

    properties (Access = private)
        InputApp % Description
    end

    methods (Access = public)
    
        function updateparameters(app, changable_inputs)
            T = changable_inputs;
            app.Inputs = T;
            app.ProjectDuration = table2array(T(ismember(T.Variable,'Project Duration'),2));
            app.Length = table2array(T(ismember(T.Variable,'Length'),2));
            app.Flow = table2array(T(ismember(T.Variable,'Flow'),2));
            app.Util = table2array(T(ismember(T.Variable,'Utilization Rate'),2));
            app.Discount = table2array(T(ismember(T.Variable,'Discount Rate'),2));
            app.MildSteel = table2array(T(ismember(T.Variable,'Mild Steel'),2));
            app.StainlessSteel  = table2array(T(ismember(T.Variable,'Stainless Steel'),2));
            
            app.TFlatRural = table2array(T(ismember(T.Variable,'Terrain - Onshore - Flat rural'),2));
            app.TUrban = table2array(T(ismember(T.Variable,'Terrain - Onshore - Urban'),2));
            app.THills= table2array(T(ismember(T.Variable,'Terrain - Onshore - Hills'),2));
            app.TMountains= table2array(T(ismember(T.Variable,'Terrain - Onshore  - Mountains'),2));
            app.TSandy= table2array(T(ismember(T.Variable,'Terrain - Offshore - Sandy seabed'),2));
            app.TTrenched= table2array(T(ismember(T.Variable,'Terrain - Offshore - Trenched'),2));
            app.TDifficult= table2array(T(ismember(T.Variable,'Terrain - Offshore - Difficult'),2));
            app.TAverage= table2array(T(ismember(T.Variable,'Terrain - Offshore - Average'),2));
            app.COnRoad= table2array(T(ismember(T.Variable,'Crossing - Onshore - Road'),2));
            app.COnPipe= table2array(T(ismember(T.Variable,'Crossing - Onshore - Pipeline'),2));
            app.COnOther= table2array(T(ismember(T.Variable,'Crossing - Onshore - Other'),2));
            app.COffPipe= table2array(T(ismember(T.Variable,'Crossing - Offshore - Pipeline'),2));
            app.COffOther = table2array(T(ismember(T.Variable,'Crossing - Offshore - Other'),2));
        end
        
    end


    methods (Access = private)

        % Button pushed function: ChangeParametersButton
        function ChangeParametersButtonPushed(app, event)
            app.ChangeParametersButton.Enable = 'off';
            app.InputApp = inputapp(app, app.Inputs);
        end

        % Button pushed function: ImportDataSetButton
        function ImportDataSetButtonPushed(app, event)
            [file, path] = uigetfile('*.xlsx');
            T = readtable(file);
            if(~isempty(T))
                app.PleaseuploaddatasetLabel.Text = 'Inputs uploaded successfully!';
            else
                app.PleaseuploaddatasetLabel.Text = 'Inputs uploaded unsuccessfully!';
            end
            T = T(1:end,2:3);
            app.ProjectDuration = str2double(T(ismember(T.Variable,'Project Duration'),2));
            app.Length = str2double(T(ismember(T.Variable,'Length'),2));
            app.Flow = str2double(T(ismember(T.Variable,'Flow'),2));
            app.Util = str2double(T(ismember(T.Variable,'Utilization Rate'),2));
            app.Discount = str2double(T(ismember(T.Variable,'Discount Rate'),2));
            app.MildSteel = str2double(T(ismember(T.Variable,'Mild Steel'),2));
            app.StainlessSteel = str2double(T(ismember(T.Variable,'Stainless Steel'),2));
            
            app.TFlatRural = str2double(T(ismember(T.Variable,'Terrain - Onshore - Flat rural'),2));
            app.TUrban = str2double(T(ismember(T.Variable,'Terrain - Onshore - Urban'),2));
            app.THills= str2double(T(ismember(T.Variable,'Terrain - Onshore - Hills'),2));
            app.TMountains= str2double(T(ismember(T.Variable,'Terrain - Onshore  - Mountains'),2));
            app.TSandy= str2double(T(ismember(T.Variable,'Terrain - Offshore - Sandy seabed'),2));
            app.TTrenched= str2double(T(ismember(T.Variable,'Terrain - Offshore - Trenched'),2));
            app.TDifficult= str2double(T(ismember(T.Variable,'Terrain - Offshore - Difficult'),2));
            app.TAverage= str2double(T(ismember(T.Variable,'Terrain - Offshore - Average'),2));
            app.COnRoad= str2double(T(ismember(T.Variable,'Crossing - Onshore - Road'),2));
            app.COnPipe= str2double(T(ismember(T.Variable,'Crossing - Onshore - Pipeline'),2));
            app.COnOther= str2double(T(ismember(T.Variable,'Crossing - Onshore - Other'),2));
            app.COffPipe= str2double(T(ismember(T.Variable,'Crossing - Offshore - Pipeline'),2));
            app.COffOther = str2double(T(ismember(T.Variable,'Crossing - Offshore - Other'),2));
            
            app.Inputs = T;
            app.ChangeParametersButton.Enable = 'on';
            app.ComputePipelineCostsButton.Enable = 'on';
            app.ComputeElectricCostsButton.Enable = 'on';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.InputApp)
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ImportDataSetButton = uibutton(app.UIFigure, 'push');
            app.ImportDataSetButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataSetButtonPushed, true);
            app.ImportDataSetButton.Text = 'Import Data Set';
            app.ChangeParametersButton = uibutton(app.UIFigure, 'push');
            app.ChangeParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeParametersButtonPushed, true);
            app.ChangeParametersButton.Enable = 'off';
            app.ChangeParametersButton.Text = 'Change Parameters';
            app.PleaseuploaddatasetLabel = uilabel(app.UIFigure);
            app.PleaseuploaddatasetLabel.Text = 'Please upload data set';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.PipelineTransportationCostModulesPanel = uipanel(app.UIFigure);
            app.PipelineTransportationCostModulesPanel.Title = 'Pipeline Transportation Cost Modules';
            app.PipeMod = uilistbox(app.PipelineTransportationCostModulesPanel);
            app.PipeMod.Items = {'Consider Costs for new Pipeline', 'Consider Labour Costs', 'Consider Compression Costs', 'Consider Electrolysis Costs'};
            app.PipeMod.Multiselect = 'on';
            app.PipeMod.Value = {'Consider Costs for new Pipeline'};
            app.UserInputsLabel = uilabel(app.UIFigure);
            app.UserInputsLabel.Text = '1. User Inputs';
            app.ElectricEnergyTransportationCostModulesPanel = uipanel(app.UIFigure);
            app.ElectricEnergyTransportationCostModulesPanel.Title = 'Electric Energy Transportation Cost Modules';
            app.ElecMod = uilistbox(app.ElectricEnergyTransportationCostModulesPanel);
            app.ElecMod.Items = {'Consider Costs for new Cable', 'Consider Labour Costs', 'Consider Alternating Current'};
            app.ElecMod.Multiselect = 'on';
            app.ElecMod.Value = {'Consider Costs for new Cable'};
            app.ComputeCostsLabel = uilabel(app.UIFigure);
            app.ComputeCostsLabel.Text = '2. Compute Costs';
            app.ComputePipelineCostsButton = uibutton(app.UIFigure, 'push');
            app.ComputePipelineCostsButton.Enable = 'off';
            app.ComputePipelineCostsButton.Text = 'Compute Pipeline Costs';
            app.ComputeElectricCostsButton = uibutton(app.UIFigure, 'push');
            app.ComputeElectricCostsButton.Enable = 'off';
            app.ComputeElectricCostsButton.Text = 'Compute Electric Costs';
            app.ResultsLabel = uilabel(app.UIFigure);
            app.ResultsLabel.Text = '3. Results';
            app.CostsPipeLamp = uilamp(app.UIFigure);
            app.CostsElecLamp = uilamp(app.UIFigure);
            app.CostsPipeLabel = uilabel(app.UIFigure);
            app.CostsPipeLabel.Text = 'Costs Pipe not yet computed';
            app.CostsElecLabel = uilabel(app.UIFigure);
            app.CostsElecLabel.Text = 'Costs Electric not yet computed';
        end
    end

    methods (Access = public)

        % Construct app
        function app = mainapp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end